classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        CalculationProgressGauge       matlab.ui.control.Gauge
        CalculationProgressGaugeLabel  matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ModeofchangePanel              matlab.ui.container.Panel
        SegmentationButtonGroup        matlab.ui.container.ButtonGroup
        SnowButton                     matlab.ui.control.ToggleButton
        ForestButton                   matlab.ui.control.ToggleButton
        LandButton                     matlab.ui.control.ToggleButton
        WaterButton                    matlab.ui.control.ToggleButton
        CityButton                     matlab.ui.control.ToggleButton
        ObserveAllButton               matlab.ui.control.ToggleButton
        Switch2                        matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
        ModeofvisualizationPanel       matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        StatisticsAnalysisButton       matlab.ui.control.ToggleButton
        TimeLapseButton                matlab.ui.control.ToggleButton
        DifferenceHighlightsButton     matlab.ui.control.ToggleButton
        LoadLocationButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_array{};          % Array that holds original images
        image_array_crop{};     % Array that holds the color adjusted images
        image_array_rec{};      % Array that hold rectified images
        image_array_rec_seg{};  % Array that hold rectified and segmented images
        image_array_diff{};     % Array that hold diff images
        nr_files;               % Number of files in array of images
        cur_image;              % Number of current image
        mask_array{};           % Array to save all segmentation masks
        file_names{};           % Array that holds the file names
        transform_array{};      % Array that holds the transformation matrix
        stat_array{};           % Array that holds statistic images
        image_array_inter{};    % Array that holds interpolated images
        array_mixed{};          % Array that hold interpolated and normal images
        nr_files_inter;         % Number of current image * 2
    end
    
    methods (Access = private)
        
        % Function to handle the preprocessing of the images
        function results = startpreprocessing(app)    
            
            % Show calculation progress
            app.CalculationProgressGauge.Visible = true;
            app.CalculationProgressGaugeLabel.Visible = true;           
            app.CalculationProgressGauge.Value = 0;           
            drawnow  
            
            % Set number of calculation steps           
            calculation_steps = 100 / ((app.nr_files*6)+app.nr_files-1);  
            
            % Crop images to remove watermark
            cropping(app);
            
            % Start image segmentation
            segmentation(app, calculation_steps);
            
            % Image registration first two images
            [im1,im2,transformation] = imageReg(app.image_array_crop{1}, app.image_array_crop{2});          
            app.image_array_rec{1} = im1;
            app.image_array_rec{2} = im2; 
            app.transform_array{1} = transformation;
            
            % Update GUI
            app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calculation_steps;
            drawnow
            
            % Image registration rest of images
            for i = 3:app.nr_files
                [~, im2, transformation] = imageReg(app.image_array_crop{1}, app.image_array_crop{i});
                app.image_array_rec{i} = im2;
                app.transform_array{i-1} = transformation;
                
                % Update GUI
                app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calculation_steps;        
                drawnow
            end     
            
            % Hide calculation progress
            app.CalculationProgressGauge.Visible = false;
            app.CalculationProgressGaugeLabel.Visible = false;
        end
        
        % Function to handle the histogram matching
        function results = starthist(app, calc_steps)
            ref = [app.image_array{:}];
            for i = 1:app.nr_files
                % Select image
                I = app.image_array{i};
                % Adjust image to reference
                app.image_array_his{i} = imhistmatch(I,ref,'method','polynomial');
                % Update GUI
                app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calc_steps;
                drawnow
            end
        end
        
        % Function to segment all images
        function results = segmentation(app, calc_steps)
            % Load prototypes
            a = load('segment_characteristics.mat');
            LAB_mean = a.LAB_mean;
            length = size(LAB_mean);            
            % Calculate segmentation over all images
            for i = 1:app.nr_files
                for j = 1:length
                    app.mask_array{i,j} = imsegDeltaE(app.image_array_crop{i},LAB_mean{j,1:3},LAB_mean{j,5});
                    % Update GUI
                    app.CalculationProgressGauge.Value = app.CalculationProgressGauge.Value + calc_steps;
                    drawnow
                end          
            end
            
        end
        
        % Function to crop all images and remove watermark
        function results = cropping(app)
            crop = [0,0,1570,1000];           
            for i = 1:app.nr_files
                app.image_array_crop{i} = imcrop(app.image_array{i},crop);
            end                     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLocationButton
        function LoadLocation(app, event)
                
            % Load all chosen images into FileNames, load path to PathName
            [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            
            % If only one image chosen, selection will be repeated until
            % min two images
            while (isa(FileNames,'char'))
                [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            end
                  
            % Get number of chosen images
            app.nr_files = length(FileNames);
            
            % Create cell array with size of number of files
            app.image_array = {app.nr_files};
            
            % Fill cell array with images
            for i = 1:app.nr_files
                
                % Create whole file name: file path + file name
                file = fullfile(PathName,FileNames{i});
                
                % Read image from file
                image = imread(file);
                
                % Save image in cell array
                app.image_array{i} = image;
                
                % Save file names in cell array
                app.file_names{i} = FileNames{i};
            end
            
            % Enable start button, disable stop button
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            
            % Visualize first image in array
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            % Display image and stretch to fill axes
            I = imshow(app.image_array{1}, 'Parent', app.UIAxes, 'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            % Remove tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];         
            axis(app.UIAxes,'off');
            % Set visibility to true
            app.Label.Text = app.file_names{1};
            app.Label.Visible = true;
            app.UIAxes.Visible = true;
            drawnow
            
            % Start preprocessing: rectify loaded images
            startpreprocessing(app);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = false;
            app.StartButton.Enable = false;
            app.StopButton.Enable = true;
            app.DifferenceHighlightsButton.Enable = false;
            app.TimeLapseButton.Enable = false;
            app.StatisticsAnalysisButton.Enable = false;
            app.Switch.Enable = false;
            app.Switch2.Enable = false;
            app.ObserveAllButton.Enable = false;
            app.CityButton.Enable = false;
            app.WaterButton.Enable = false;
            app.LandButton.Enable = false;
            app.ForestButton.Enable = false;
            app.SnowButton.Enable = false;
          
            % Check which mode of visualization is set
            
            % =============================================================
            % Mode of visualization is time lapse
            
            if app.TimeLapseButton.Value == true
                
                app.Label.Visible = true;
                
                % Check which  mode of change is set
                
                % =========================================================
                % Mode of change is Observe All
                
                if app.ObserveAllButton.Value == true
                    
                    % Check mode of change Faster | Slower
                    if strcmp(app.Switch2.Value,'Slower')
                        
                        app.Label.Visible = false;
                        
                        for i = 1:(app.nr_files-1)
                            interpolated_image = interpolate_images(app.image_array_rec{i}, app.image_array_rec{i+1});
                            app.image_array_inter{i} = interpolated_image;
                        end 
                        vec = [app.image_array_rec(1:end-1); app.image_array_inter];
                        app.array_mixed = vec(:);
                        
                        % Show first image of array 
                        imshow(app.array_mixed{1}, 'Parent', app.UIAxes, ...
                            'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        
                        app.nr_files_inter = app.nr_files * 2 - 2;
                    else
                        % Show first image of array
                        imshow(app.image_array_rec{1}, 'Parent', app.UIAxes, ...
                            'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        app.Label.Text = app.file_names{1};
                    end                   
                    
                else     
                    
                    % =====================================================
                    % Mode of change is rest
                    
                    if app.ForestButton.Value == true
                        % Mode of change is Forest
                        mask_index = 1;
                    elseif app.SnowButton.Value == true    
                        % Mode of change is Snow
                        mask_index = 2;
                    elseif app.WaterButton.Value == true 
                        % Mode of change is Water
                        mask_index = 3;
                        mask_index_poluted = 6;
                    elseif app.LandButton.Value == true 
                        % Mode of change is Land
                        mask_index = 4;
                    elseif app.CityButton.Value == true 
                        % Mode of change is City
                        mask_index = 5;
                    end
                    
                    % Calculate mask on rectified image
                    i1 = app.image_array_crop{1};
                    if app.WaterButton.Value == true
                        mask = app.mask_array{1,mask_index} | app.mask_array{1,mask_index_poluted};
                        image_seg = bsxfun(@times, i1, cast(app.mask_array{1,mask_index}, class(i1))); 
                    else
                        image_seg = bsxfun(@times, i1, cast(app.mask_array{1,mask_index}, class(i1)));
                    end                   
                    app.image_array_rec_seg{1} = image_seg;
                    % Set viewport for image registration
                    Rfixed = imref2d(size(i1));
                    
                    % Calculate mask and rectify images
                    for i = 2:app.nr_files
                        
                        % If selection is water, binary OR clean and
                        % polluted water first
                        if app.WaterButton.Value == true
                            mask = app.mask_array{i,mask_index} | app.mask_array{i,mask_index_poluted};
                            image_seg = bsxfun(@times, app.image_array_crop{i}, cast(mask, class(app.image_array_crop{i})));    
                        else
                            image_seg = bsxfun(@times, app.image_array_crop{i}, cast(app.mask_array{i,mask_index}, class(app.image_array_crop{i})));    
                        end
                        app.image_array_rec_seg{i} = imwarp(image_seg,app.transform_array{i-1},'OutputView',Rfixed);
                    end                   
                    
                    % Check mode of change Faster | Slower
                    if strcmp(app.Switch2.Value,'Slower')
                        
                        app.Label.Visible = false;
                        
                        for i = 1:(app.nr_files-1)
                            interpolated_image = interpolate_images(app.image_array_rec_seg{i}, app.image_array_rec_seg{i+1});
                            app.image_array_inter{i} = interpolated_image;
                        end 
                        vec = [app.image_array_rec_seg(1:end-1); app.image_array_inter];
                        app.array_mixed = vec(:);
                        
                        % Show first image of array 
                        imshow(app.array_mixed{1}, 'Parent', app.UIAxes, ...
                            'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        
                        app.nr_files_inter = app.nr_files * 2 - 2;
                    else
                        % Show first image of array
                        imshow(app.image_array_rec_seg{1}, 'Parent', app.UIAxes, ...
                            'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                    end
                    
                    app.Label.Text = app.file_names{1};
                    
                end
                
                % Set current image counter to one
                app.cur_image = 1;
                
                % Make next and previous button visible
                app.NextButton.Visible = true;
                app.PreviousButton.Visible = true;
            end
           
            % =============================================================
            % Mode of visualization is statistics
            
            if app.StatisticsAnalysisButton.Value == true
                
                app.Label.Visible = false;
                
                if app.ForestButton.Value == true
                    % Set parameter         
                    mask = app.mask_array(:,1);
                    type = 'Forest';
                    names = app.file_names;  
                elseif app.SnowButton.Value == true
                    % Set parameter         
                    mask = app.mask_array(:,2);
                    type = 'Snow';
                    names = app.file_names;
                elseif app.WaterButton.Value == true
                    % Set parameter  
                    for i = 1:app.nr_files
                        mask{i} = app.mask_array{i,3} | app.mask_array{i,6};
                    end
                    mask = mask';
                    type = 'Water';
                    names = app.file_names;
                elseif app.LandButton.Value == true
                    % Set parameter         
                    mask = app.mask_array(:,4);
                    type = 'Land';
                    names = app.file_names;
                elseif app.CityButton.Value == true
                    % Set parameter         
                    mask = app.mask_array(:,5);
                    type = 'Manmade';
                    names = app.file_names;
                elseif app.ObserveAllButton.Value == true
                    % Set parameter       
                    for i = 1:app.nr_files
                        mask_water{i} = app.mask_array{i,3} | app.mask_array{i,6};
                    end
                    temp = mask_water';
                    mask = app.mask_array(:,1:5);
                    mask(:,3) = temp;
                    type = 'all';
                    names = app.file_names;                
                end
                
                % Calculate statistics 
                [~, image1,image2] = Statistic(mask, type, app.image_array, names); 
                app.stat_array{1} = image1;
                app.stat_array{2} = image2;
                
                % Display output
                imshow(app.stat_array{1}, 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
                
                app.cur_image = 1;
                
                % Make next and previous button visible
                app.NextButton.Visible = true;
                app.PreviousButton.Visible = true;
                
            end
            
            % =============================================================
            % Mode of visualization is difference highlights
            
            if app.DifferenceHighlightsButton.Value == true    
                
                app.Label.Visible = true;
                
                % Check which  mode of change is set
                    
                if app.ForestButton.Value == true
                    % Mode of change is Forest
                    mask_index = 1;
                elseif app.SnowButton.Value == true    
                    % Mode of change is Snow
                    mask_index = 2;
                elseif app.WaterButton.Value == true 
                    % Mode of change is Water
                    mask_index = 3;
                    mask_index_poluted = 6;
                elseif app.LandButton.Value == true 
                    % Mode of change is Land
                    mask_index = 4;
                elseif app.CityButton.Value == true 
                    % Mode of change is City
                    mask_index = 5;    
                end
                    
                % Calculate mask on rectified image
                i1 = app.image_array_crop{1};
                if app.WaterButton.Value == true
                    mask = app.mask_array{1,mask_index} | app.mask_array{1,mask_index_poluted};
                    image_seg = bsxfun(@times, i1, cast(mask, class(i1))); 
                else
                    image_seg = bsxfun(@times, i1, cast(app.mask_array{1,mask_index}, class(i1)));
                end                   
                app.image_array_rec_seg{1} = image_seg;
                
                % Set viewport for image registration
                Rfixed = imref2d(size(i1));
                    
                % Calculate mask and rectify images
                for i = 2:app.nr_files
                        
                    % If selection is water, binary OR clean and
                    % polluted water first
                    if app.WaterButton.Value == true
                        mask = app.mask_array{i,mask_index} | app.mask_array{i,mask_index_poluted};
                        image_seg = bsxfun(@times, app.image_array_crop{i}, cast(mask, class(app.image_array_crop{i})));    
                    else
                        image_seg = bsxfun(@times, app.image_array_crop{i}, cast(app.mask_array{i,mask_index}, class(app.image_array_crop{i})));    
                    end
                    app.image_array_rec_seg{i} = imwarp(image_seg,app.transform_array{i-1},'OutputView',Rfixed);
                end
            
                % Calculate binarized mask of difference highlights
                for i = 1:app.nr_files-1
                    % Binarize segmented images
                    temp1 = imbinarize(app.image_array_rec_seg{i+1});
                    temp2 = imbinarize(app.image_array_rec_seg{i});
                    % Get changes between images from xor
                    temp3 = uint8(xor(temp1,temp2));
                    % Emphasize changes
                    temp4 = temp3(:,:,1)*255;
                    % Save changes in array
                    app.image_array_diff{i} = temp4;
                end
                
                % ====================================================
                % Mode of change is Larger Differences
                if strcmp(app.Switch.Value,'Larger')
                    for i = 1:app.nr_files-1
                        app.image_array_diff{i} = medfilt2(app.image_array_diff{i},[7 7]);
                    end
                end
                    
                % Show first image of array
                imshow(app.image_array_rec{1}, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                hold(app.UIAxes);
                imshow(app.image_array_diff{1}, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                app.Label.Text = strcat(app.file_names(1),' and ', app.file_names(2)); 
                
                % Set current image counter to one
                app.cur_image = 1;
                
                % Make next and previous button visible
                app.NextButton.Visible = true;
                app.PreviousButton.Visible = true;
                
            end   
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = true;
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            app.DifferenceHighlightsButton.Enable = true;
            app.TimeLapseButton.Enable = true;
            app.StatisticsAnalysisButton.Enable = true;
            app.Switch.Enable = true;
            app.Switch2.Enable = true;
            app.ObserveAllButton.Enable = true;
            app.CityButton.Enable = true;
            app.WaterButton.Enable = true;
            app.LandButton.Enable = true;
            app.ForestButton.Enable = true;
            app.SnowButton.Enable = true;
            
            % Buttons for time lapse function
            app.PreviousButton.Visible = false;
            app.NextButton.Visible = false;
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            % Check which mode of visualization is set
            
            % ========================================================
            % Mode is TimeLapse       
            if app.TimeLapseButton.Value == true  
                if strcmp(app.Switch2.Value,'Slower')
                    if app.cur_image == 1
                        app.cur_image = app.nr_files_inter;
                    else
                        app.cur_image = app.cur_image - 1;
                    end
                  
                    imshow(app.array_mixed{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                        [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                else
                    % Check if first image in array is selected. If yes start
                    % with last image in array
                    if app.cur_image == 1
                        app.cur_image = app.nr_files;
                    else
                        app.cur_image = app.cur_image - 1;
                    end
                
                    % Check which segmentation mode is set
                    if app.ObserveAllButton.Value == true
                        imshow(app.image_array_rec{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                            [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        app.Label.Text = app.file_names{app.cur_image};
                    else
                        imshow(app.image_array_rec_seg{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                            [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        app.Label.Text = app.file_names{app.cur_image};
                    end
                end
            end
            
            % ========================================================
            % Mode is Difference Highlights
            if app.DifferenceHighlightsButton.Value == true
                
                % Check if first image in array is selected. If yes start
                % with last image in array
                if app.cur_image == 1
                    app.cur_image = app.nr_files - 1;
                else
                    app.cur_image = app.cur_image - 1;
                end
                
                imshow(app.image_array_diff{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                app.Label.Text = strcat(app.file_names(app.cur_image-1),' and ', app.file_names(app.cur_image));
            end
            
            % ========================================================
            % Mode is Statistics
            if app.StatisticsAnalysisButton.Value == true
                if app.cur_image == 1
                    app.cur_image = 2;
                else
                    app.cur_image = 1;
                end
                
                % Display output
                imshow(app.stat_array{app.cur_image}, 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            % Check which mode of visualization is set
            
            % ========================================================
            % Mode is TimeLapse
            if app.TimeLapseButton.Value == true 
                
                % Check if last image in array is selected. If yes start
                % with first image in array
                
                if strcmp(app.Switch2.Value,'Slower')
                    if app.cur_image == app.nr_files_inter
                        app.cur_image = 1;
                    else
                        app.cur_image = app.cur_image + 1;
                    end
                  
                    imshow(app.array_mixed{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                        [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                    
                else                    
                    if app.cur_image == app.nr_files
                        app.cur_image = 1;
                    else
                        app.cur_image = app.cur_image + 1;
                    end
                    
                    % Check which segmentation mode is set
                    if app.ObserveAllButton.Value == true
                        imshow(app.image_array_rec{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                            [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        app.Label.Text = app.file_names{app.cur_image};
                    else
                        imshow(app.image_array_rec_seg{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                            [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                        app.Label.Text = app.file_names{app.cur_image};
                    end
                end
            end
            
            % ========================================================
            % Mode is Difference Highlights
            if app.DifferenceHighlightsButton.Value == true
                
                % Check if last image in array is selected. If yes start
                % with first image in array
                if app.cur_image == app.nr_files - 1 
                    app.cur_image = 1;
                else
                    app.cur_image = app.cur_image + 1;
                end
                
                imshow(app.image_array_diff{app.cur_image}, 'Parent', app.UIAxes, 'XData', ...
                    [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
                app.Label.Text = strcat(app.file_names(app.cur_image-1),' and ', app.file_names(app.cur_image));
            end
            
            % ========================================================
            % Mode is Statistics
            if app.StatisticsAnalysisButton.Value == true
                if app.cur_image == 2
                    app.cur_image = 1;
                else
                    app.cur_image = 2;
                end
                
                % Display output
                imshow(app.stat_array{app.cur_image}, 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
            end
        end

        % Button down function: ModeofvisualizationPanel
        function ModeofvisualizationPanelButtonDown(app, event)
            if app.DifferenceHighlightsButton.Value == true
                app.CityButton.Value = true;
                app.ObserveAllButton.Enable = false;
            else
                app.ObserveAllButton.Enable = true;
            end
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            if app.DifferenceHighlightsButton.Value == true
                app.CityButton.Value = true;
                app.ObserveAllButton.Enable = false;
            else
                app.ObserveAllButton.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadLocationButton = uibutton(app.GridLayout2, 'push');
            app.LoadLocationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocation, true);
            app.LoadLocationButton.Text = {'Load Location'; ''};
            app.ModeofvisualizationPanel = uipanel(app.GridLayout2);
            app.ModeofvisualizationPanel.Title = 'Mode of visualization';
            app.ModeofvisualizationPanel.ButtonDownFcn = createCallbackFcn(app, @ModeofvisualizationPanelButtonDown, true);
            app.ButtonGroup = uibuttongroup(app.ModeofvisualizationPanel);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.DifferenceHighlightsButton = uitogglebutton(app.ButtonGroup);
            app.DifferenceHighlightsButton.Text = {'Difference-Highlights'; ''};
            app.TimeLapseButton = uitogglebutton(app.ButtonGroup);
            app.TimeLapseButton.Text = {'Time-Lapse'; ''};
            app.TimeLapseButton.Value = true;
            app.StatisticsAnalysisButton = uitogglebutton(app.ButtonGroup);
            app.StatisticsAnalysisButton.Text = 'Statistics / Analysis';
            app.ModeofchangePanel = uipanel(app.GridLayout2);
            app.ModeofchangePanel.Title = 'Mode of change';
            app.Switch = uiswitch(app.ModeofchangePanel, 'slider');
            app.Switch.Items = {'All', 'Larger'};
            app.Switch.Value = 'All';
            app.Switch2 = uiswitch(app.ModeofchangePanel, 'slider');
            app.Switch2.Items = {'Faster', 'Slower'};
            app.Switch2.Value = 'Faster';
            app.SegmentationButtonGroup = uibuttongroup(app.ModeofchangePanel);
            app.SegmentationButtonGroup.Title = 'Segmentation';
            app.ObserveAllButton = uitogglebutton(app.SegmentationButtonGroup);
            app.ObserveAllButton.Text = 'Observe All';
            app.ObserveAllButton.Value = true;
            app.CityButton = uitogglebutton(app.SegmentationButtonGroup);
            app.CityButton.Text = 'City';
            app.WaterButton = uitogglebutton(app.SegmentationButtonGroup);
            app.WaterButton.Text = 'Water';
            app.LandButton = uitogglebutton(app.SegmentationButtonGroup);
            app.LandButton.Text = 'Land';
            app.ForestButton = uitogglebutton(app.SegmentationButtonGroup);
            app.ForestButton.Text = 'Forest';
            app.SnowButton = uitogglebutton(app.SegmentationButtonGroup);
            app.SnowButton.Text = 'Snow';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Panel_2 = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.Visible = 'off';
            app.PreviousButton = uibutton(app.Panel_2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.Panel_2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.CalculationProgressGaugeLabel = uilabel(app.Panel_2);
            app.CalculationProgressGaugeLabel.Visible = 'off';
            app.CalculationProgressGaugeLabel.Text = 'Calculation Progress (%)';
            app.CalculationProgressGauge = uigauge(app.Panel_2, 'circular');
            app.CalculationProgressGauge.Visible = 'off';
            app.Label = uilabel(app.Panel_2);
            app.Label.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end